classdef figure_extractor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        track_extractor          matlab.ui.Figure
        Vvsy_axes                matlab.ui.control.UIAxes
        Vbyyvsy_axes             matlab.ui.control.UIAxes
        InformationLabel         matlab.ui.control.Label
        Information              matlab.ui.control.TextArea
        PrevtrackButton          matlab.ui.control.Button
        SavedataButton           matlab.ui.control.Button
        NexttrackButton          matlab.ui.control.Button
        SelectpieceoftrackPanel  matlab.ui.container.Panel
        y_startEditFieldLabel    matlab.ui.control.Label
        y_startEditField         matlab.ui.control.NumericEditField
        y_endEditFieldLabel      matlab.ui.control.Label
        y_endEditField           matlab.ui.control.NumericEditField
        ResetButton              matlab.ui.control.Button
        LoadStartButton          matlab.ui.control.Button
    end

    
    properties (Access = private)
        appData % Description - information about loading tracks in the app
        gety_start = false % Description - Bool (if the point from the plot needs to be read or not)
        gety_end = false % Description
        state_subset % Description
        plotHandle % Description
    end
    
    properties (Access = public)
        tracks % Description - Instances of Landingtrack class
    end
    
    methods (Access = private)


        function displayInfo(app, information)
            % information - string
            app.Information.Value = information;
        end
        
        function test(app)
            app.displayInfo(num2str(app.tracks(1).obj_id))
            app.tracks(1).obj_id = 84;
            app.displayInfo(num2str(app.tracks(1).obj_id))
        end
        
        function plotTrackData(app, track)
            % track - instance of Landingtrack class
            
%             track.drawVvsy(app.Vvsy_axes);
%             track.drawVbyyvsy(app.Vbyyvsy_axes);
            
            app.state_subset = track.state_LDF(track.time>=-1.5,:);
            plot(app.Vvsy_axes, app.state_subset(:,3),app.state_subset(:,6),'m.','MarkerSize',10);
            p = plot(app.Vbyyvsy_axes, app.state_subset(:,3),app.state_subset(:,6)./app.state_subset(:,3),'m.','MarkerSize',10);
            
            
            app.Vvsy_axes.XLim = [0 0.2];
            app.Vbyyvsy_axes.XLim = [0 0.2];
            
            app.Vbyyvsy_axes.ButtonDownFcn = @Vbyyvsy_axesButtonDown;
            
            function Vbyyvsy_axesButtonDown(~,~)
                if app.gety_start
                    cp=get(app.Vbyyvsy_axes,'CurrentPoint');
                    app.y_startEditField.Value = cp(1,1);
                elseif app.gety_end
                    cp=get(app.Vbyyvsy_axes,'CurrentPoint');
                    app.y_endEditField.Value = cp(1,1);
                end
            end
            
        end
        
        
        function Vbyyvsy_axesButtonDown(app)
            if app.gety_start
                cp=get(app.Vbyyvsy_axes,'CurrentPoint');
                app.y_startEditField.Value = cp(1,1);
            elseif app.gety_end
                cp=get(app.Vbyyvsy_axes,'CurrentPoint');
                app.y_endEditField.Value = cp(1,1);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            try
%                 app.displayInfo('relevantTracks variable exists! Loading it by reference...')
                app.tracks = evalin("base","relevantTracks"); % It is loading by reference.
                information = 'relevantTracks variable exists! Loading it by reference...';
            catch
                information = 'relevantTracks variable does NOT exist :(';
%                 app.displayInfo('relevantTracks variable does NOT exist :(')
            end
            
            try
                app.appData = evalin("base","appData"); % It is loading by reference.
                information = [information ' Also, loading existing appData'];
            catch
                app.appData = appData_trackExtractionSysID();
                information = [information ' appData does NOT exist. Creating a new one.'];
            end
            
            app.displayInfo(information);
            app.plotHandle = figure;
        end

        % Button pushed function: LoadStartButton
        function LoadStartButtonPushed(app, event)
            information = ['Loading track ' num2str(app.appData.currentTrack) '.'];
            app.displayInfo(information);
            
            track = app.tracks(app.appData.currentTrack);
%             app.plotTrackData(track);
            track.drawOpticalExpansion(app.plotHandle);
        end

        % Key press function: track_extractor
        function track_extractorKeyPress(app, event)
            key = event.Key;
            if strcmpi(key, 'shift')
                if ~app.gety_start
                    app.displayInfo('In getting y_start mode. Press shift key to toggle.');
                else
                    app.displayInfo('');
                end
                app.gety_start = ~app.gety_start;
                
            elseif strcmpi(key, 'control')
                if ~app.gety_end
                    app.displayInfo('In getting y_end mode. Press ctrl key to toggle.');
                else
                    app.displayInfo('');
                end
                app.gety_end = ~app.gety_end;
                
%             elseif strcmpi(key, 'space')
%                 [x, y] = ginput(2);
%                 app.displayInfo(num2str(x));
                
            end
%             https://nl.mathworks.com/matlabcentral/answers/433573-app-designer-how-to-link-axes-of-uiaxes-components-to-enable-user-to-zoom-all-plots-together-in-ap
        end

        % Window button down function: track_extractor
        function track_extractorWindowButtonDown(app, event)
%             keyboard
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.track_extractor = uifigure('Visible', 'off');
            app.track_extractor.Name = 'UI Figure';
            app.track_extractor.WindowButtonDownFcn = createCallbackFcn(app, @track_extractorWindowButtonDown, true);
            app.track_extractor.KeyPressFcn = createCallbackFcn(app, @track_extractorKeyPress, true);
            app.Vvsy_axes = uiaxes(app.track_extractor);
            title(app.Vvsy_axes, '')
            xlabel(app.Vvsy_axes, '')
            ylabel(app.Vvsy_axes, 'V_{gy} (m/s)')
            app.Vbyyvsy_axes = uiaxes(app.track_extractor);
            title(app.Vbyyvsy_axes, '')
            xlabel(app.Vbyyvsy_axes, 'y (m)')
            ylabel(app.Vbyyvsy_axes, 'V_{gy} / y (1/s)')
            app.InformationLabel = uilabel(app.track_extractor);
            app.InformationLabel.Text = 'Information';
            app.Information = uitextarea(app.track_extractor);
            app.PrevtrackButton = uibutton(app.track_extractor, 'push');
            app.PrevtrackButton.Text = 'Prev track';
            app.SavedataButton = uibutton(app.track_extractor, 'push');
            app.SavedataButton.Text = 'Save data';
            app.NexttrackButton = uibutton(app.track_extractor, 'push');
            app.NexttrackButton.Text = 'Next track';
            app.SelectpieceoftrackPanel = uipanel(app.track_extractor);
            app.SelectpieceoftrackPanel.Title = 'Select piece of track';
            app.y_startEditFieldLabel = uilabel(app.SelectpieceoftrackPanel);
            app.y_startEditFieldLabel.Text = 'y_start';
            app.y_startEditField = uieditfield(app.SelectpieceoftrackPanel, 'numeric');
            app.y_startEditField.Limits = [0 Inf];
            app.y_endEditFieldLabel = uilabel(app.SelectpieceoftrackPanel);
            app.y_endEditFieldLabel.Text = 'y_end';
            app.y_endEditField = uieditfield(app.SelectpieceoftrackPanel, 'numeric');
            app.ResetButton = uibutton(app.SelectpieceoftrackPanel, 'push');
            app.ResetButton.Text = 'Reset';
            app.LoadStartButton = uibutton(app.track_extractor, 'push');
            app.LoadStartButton.ButtonPushedFcn = createCallbackFcn(app, @LoadStartButtonPushed, true);
            app.LoadStartButton.Text = 'Load/Start';
            % Show the figure after all components are created
            app.track_extractor.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = figure_extractor

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.track_extractor)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.track_extractor)
        end
    end
end